classdef myImageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageedgedetectionandenhancementtoolboxUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportMenu                     matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        UIAxesOriginal                 matlab.ui.control.UIAxes
        UIAxesEdge                     matlab.ui.control.UIAxes
        UIAxesSharpening               matlab.ui.control.UIAxes
        controlTabGroup                matlab.ui.container.TabGroup
        SetthresholdTab                matlab.ui.container.Tab
        UIAxesHistogram                matlab.ui.control.UIAxes
        SetthresholdSlider             matlab.ui.control.Slider
        DenoiseTab                     matlab.ui.container.Tab
        denoiseTabGroup                matlab.ui.container.TabGroup
        MeanTab                        matlab.ui.container.Tab
        TemplatesizeButtonGroup        matlab.ui.container.ButtonGroup
        customizeButton                matlab.ui.control.RadioButton
        widthEditFieldLabel            matlab.ui.control.Label
        widthEditField                 matlab.ui.control.NumericEditField
        heightEditFieldLabel           matlab.ui.control.Label
        heightEditField                matlab.ui.control.NumericEditField
        x1Button                       matlab.ui.control.RadioButton
        x3Button_8                     matlab.ui.control.RadioButton
        x5Button_8                     matlab.ui.control.RadioButton
        x7Button_8                     matlab.ui.control.RadioButton
        MedianTab                      matlab.ui.container.Tab
        TemplatesizeButtonGroup_5      matlab.ui.container.ButtonGroup
        x3Button_7                     matlab.ui.control.RadioButton
        x5Button_7                     matlab.ui.control.RadioButton
        x7Button_7                     matlab.ui.control.RadioButton
        x1Button_2                     matlab.ui.control.RadioButton
        customizeButton_2              matlab.ui.control.RadioButton
        widthEditField_2Label          matlab.ui.control.Label
        widthEditField_2               matlab.ui.control.NumericEditField
        heightEditField_2Label         matlab.ui.control.Label
        heightEditField_2              matlab.ui.control.NumericEditField
        GaussianTab                    matlab.ui.container.Tab
        SetsigmaButtonGroup            matlab.ui.container.ButtonGroup
        SetSigmaSlider                 matlab.ui.control.Slider
        SharpeningTab                  matlab.ui.container.Tab
        SharpeningApplyCheckBox        matlab.ui.control.CheckBox
        SharpnessSliderLabel           matlab.ui.control.Label
        SharpnessSlider                matlab.ui.control.Slider
        operatorTabGroup               matlab.ui.container.TabGroup
        SobelTab                       matlab.ui.container.Tab
        Sobel1CheckBox                 matlab.ui.control.CheckBox
        Sobel2CheckBox                 matlab.ui.control.CheckBox
        LaplaceTab                     matlab.ui.container.Tab
        SetLaplaceoperatorButtonGroup  matlab.ui.container.ButtonGroup
        Laplace1Button                 matlab.ui.control.RadioButton
        Laplace2Button                 matlab.ui.control.RadioButton
        Laplace3Button                 matlab.ui.control.RadioButton
        Laplace4Button                 matlab.ui.control.RadioButton
        PrewittTab                     matlab.ui.container.Tab
        Prewitt1CheckBox               matlab.ui.control.CheckBox
        Prewitt2CheckBox               matlab.ui.control.CheckBox
        RobertsTab                     matlab.ui.container.Tab
        Roberts1CheckBox               matlab.ui.control.CheckBox
        Roberts2CheckBox               matlab.ui.control.CheckBox
        CannyTab                       matlab.ui.container.Tab
        GradientlowthresholdEditFieldLabel  matlab.ui.control.Label
        CannyLowEditField              matlab.ui.control.NumericEditField
        GradienthighthresholdEditFieldLabel  matlab.ui.control.Label
        CannyHighEditField             matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
    end


    properties (Access = private)
        imageOriginal = -1; % The input image
        imagePreprocessed = -1; % Preprocessed original image
        imageGrayscale = -1; % grayscale image or intensity dimension
        filterResMap = containers.Map(); % Operator filter result of preprocessed image
        imageEdge = -1; % Edge detection image of preprocessed image (one dimension & binary result)
        imageSharpening = -1; % The addition of preprocessed image and 
        isRGB = false; % Default: gray
        denoise = 'Mean'; % No default denoise method
        operator = 'Sobel'; % Default operator: Sobel
        templateWidth = 1;
        templateHeight = 1;
    end

    methods (Access = private)
        
        function preprocessImage(app, image)
            % clear some variables (since the preprocessed image will be regenerated)
            app.filterResMap = containers.Map();

            % start preprocess
            if length(size(image)) == 3
                app.imagePreprocessed = rgb2hsi(image); % convert rgb to hsi format
                app.imageGrayscale = im2uint8(app.imagePreprocessed(:,:,3)); % get intensity
                app.isRGB = true;
            else
                app.imagePreprocessed = image; % remain the gray image
                app.imageGrayscale = im2uint8(app.imagePreprocessed);
                app.isRGB = false;
            end
            frequency = grayscalehistogram(app.imageGrayscale); % draw grayscale histogram
            bar(app.UIAxesHistogram, 0:255, frequency);
            axis(app.UIAxesHistogram, 'tight');
            updateOperator(app);
        end
 
        function updateDenoise(app)
%             disp('updateDenoise() is called');
            if app.imageOriginal ~= -1
                if app.denoise ~= -1
                    width = app.templateWidth;
                    height = app.templateHeight;
%                     addpath('functions/denoise');
                    switch app.denoise
                        case 'Mean'
                            image = meanfilter(app.imageOriginal, width, height);
                        case 'Median'
                            image = medianfilter(app.imageOriginal, width, height);
                        case 'Gaussian'
                            value = round(app.SetSigmaSlider.Value);
                            if value > 0
                                image = imgaussfilt(app.imageOriginal, round(app.SetSigmaSlider.Value));
                            else
                                image = app.imageOriginal;
                            end
                    end
%                     disp('updateDenoise() is running');
%                     disp(width);
%                     disp(height);
                    preprocessImage(app, image);
                end
            end
        end
        
        function updateOperator(app)
            % Get grayscale or intensity
            grayscale = app.imageGrayscale;
            % Filter the image with the specified filter (operator)
            if grayscale ~= -1
%                 if ~app.filterResMap.isKey(app.operator)
                    keys = {};
                    switch app.operator
                        case 'Canny'
                            lowThreshold = app.CannyLowEditField.Value;
                            highThreshold = app.CannyHighEditField.Value;
                            [Iedge, Gstrongedge] = cannyfilter2(grayscale, lowThreshold, highThreshold);
                            app.filterResMap('Canny') = Iedge;
                            keys{1} = 'Canny';
                        case 'Laplace'
                            laplacianoperator = {[0,1,0;1,-4,1;0,1,0], [1,1,1;1,-8,1;1,1,1], [0,-1,0;-1,4,-1;0,-1,0], [-1,-1,-1;-1,8,-1;-1,-1,-1]};
                            text = app.SetLaplaceoperatorButtonGroup.SelectedObject.Text;
                            keys{1} = ['Laplace', text(18)];
                            if ~app.filterResMap.isKey(keys{1})
                                app.filterResMap(keys{1}) = myfilter(grayscale,laplacianoperator{str2double(text(18))});
                            end
                        case 'Prewitt'
                            prewittoperator = {[-1,-1,-1;0,0,0;1,1,1], [-1,0,1;-1,0,1;-1,0,1]};
                            if app.Prewitt1CheckBox.Value
                                keys{1} = 'Prewitt1';
                                if ~app.filterResMap.isKey(keys{1})
                                    app.filterResMap(keys{1}) = myfilter(grayscale, prewittoperator{1});
                                end
                            end
                            if app.Prewitt2CheckBox.Value
                                keys{2} = 'Prewitt2';
                                if ~app.filterResMap.isKey(keys{2})
                                    app.filterResMap(keys{2}) = myfilter(grayscale, prewittoperator{2});
                                end
                            end
                        case 'Roberts'
                            robertsoperator = {[0,0,0;0,-1,0;0,0,1], [0,0,0;0,0,-1;0,1,0]};
                            if app.Roberts1CheckBox.Value
                                keys{1} = 'Roberts1';
                                if ~app.filterResMap.isKey(keys{1})
                                    app.filterResMap(keys{1}) = myfilter(grayscale, robertsoperator{1});
                                end
                            end
                            if app.Roberts2CheckBox.Value
                                keys{2} = 'Roberts2';
                                if ~app.filterResMap.isKey(keys{2})
                                    app.filterResMap(keys{2}) = myfilter(grayscale, robertsoperator{2});
                                end
                            end
                        case 'Sobel'
                            sobeloperator = {[-1,0,1;-2,0,2;-1,0,1], [-1,-2,-1;0,0,0;1,2,1]};
                            if app.Sobel1CheckBox.Value
                                keys{1} = 'Sobel1';
                                if ~app.filterResMap.isKey(keys{1})
                                    app.filterResMap(keys{1}) = myfilter(grayscale, sobeloperator{1});
                                end
                            end
                            if app.Sobel2CheckBox.Value
                                keys{2} = 'Sobel2';
                                if ~app.filterResMap.isKey(keys{2})
                                    app.filterResMap(keys{2}) = myfilter(grayscale, sobeloperator{2});
                                end
                            end
                    end
%                 end
                updateEdge(app, keys);
                updateSharpening(app, keys);
            end
        end

        % Binarization
        function updateEdge(app, keys)
            threshold = app.SetthresholdSlider.Value;
%             binarizationRes = app.filterResMap(app.operator);
            binarizationRes = uint8(zeros(size(app.imageGrayscale)));
            for i = 1:length(keys)
                if ~isempty(keys{i})
                    binarizationRes = imadd(binarizationRes, app.filterResMap(keys{i}));
                end
            end
            if threshold ~= 0
                binarizationRes(binarizationRes<threshold)=0;
                binarizationRes(binarizationRes>=threshold)=255;
            end
            app.imageEdge = binarizationRes;
            imshow(app.imageEdge, 'Parent', app.UIAxesEdge);
        end
    
        % Sharpening
        function updateSharpening(app, keys)
            sharpeningPart = uint8(zeros(size(app.imageGrayscale)));
            for i = 1:length(keys)
                if ~isempty(keys{i})
                    sharpeningPart = imadd(sharpeningPart, app.filterResMap(keys{i}));
                end
            end
            if app.SharpnessSlider.Value > 0 % Similar to transparency
                sharpeningPart = sharpeningPart/100*app.SharpnessSlider.Value;
            end
            if app.SharpeningApplyCheckBox.Value
                image = app.imagePreprocessed; % hsi format (if rgb)
            else
                image = app.imageOriginal; % rgb format (if rgb)
                if app.isRGB
                    image = rgb2hsi(image); % hsi format (if rgb)
                end
            end
%             threshold = app.SetthresholdSlider.Value;
%             if threshold ~= 0 % edges whose grayscale are lower than threshold will be ignored
%                 sharpeningPart(sharpeningPart<threshold)=0;
%             end
            if app.isRGB
                img = image; % hsi format (double)
                img(:,:,3) = img(:,:,3) + sharpeningPart;
                app.imageSharpening = hsi2rgb(img);
            else
                app.imageSharpening = imadd(image, sharpeningPart);
            end
            imshow(app.imageSharpening, 'Parent', app.UIAxesSharpening);
        end
    
    end


    methods (Access = private)

        % Value changed function: SetthresholdSlider
        function SetthresholdSliderValueChanged(app, event)
            updateOperator(app);
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
%             addpath('functions/denoise');
%             addpath('functions/operator');
%             addpath('functions/utils');
            [filename, pathname, filterindex] = uigetfile({'*.png;*.jpg', 'JPG or PNG (*.jpg, *.png)'}, 'Pick an image');
            switch filterindex
                case 1
                    image = imread([pathname, filename]);
                    imshow(image, 'Parent', app.UIAxesOriginal); % display original image
                    app.imageOriginal = image;
                    preprocessImage(app, image);
            end
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            [filename, pathname, filterindex] = uiputfile({'*.png;*.jpg', 'JPG or PNG (*.jpg, *.png)'}, 'Save as');
            switch filterindex
                case 1
%                     imwrite(app.imageOriginal, [pathname, 'original_', filename]); % There is no need to store the original image (maybe)
                    imwrite(app.imageEdge, [pathname, 'edge_', filename]);
                    imwrite(app.imageSharpening, [pathname, 'sharpening_', filename]);
            end
                    
        end

        % Selection change function: operatorTabGroup
        function operatorTabGroupSelectionChanged(app, event)
            app.operator = app.operatorTabGroup.SelectedTab.Title; % set to the choosed operator
            updateOperator(app);
        end

        % Selection change function: denoiseTabGroup
        function denoiseTabGroupSelectionChanged(app, event)
            app.denoise = app.denoiseTabGroup.SelectedTab.Title;
            updateDenoise(app);
        end

        % Selection changed function: TemplatesizeButtonGroup, 
        % TemplatesizeButtonGroup_5
        function TemplatesizeButtonGroupSelectionChanged(app, event)
%             disp(event)
%             selectedButton = app.TemplatesizeButtonGroup.SelectedObject;
%             text = selectedButton.Text;
            text = event.NewValue.Text;
            if ~strcmp(text, 'customize')
                app.templateWidth = str2double(text(1));
                app.templateHeight = str2double(text(3));
                updateDenoise(app);
            else
                if app.widthEditField.Value > 0
                    app.templateWidth = app.widthEditField.Value;
                    if app.heightEditField.Value > 0
                        app.templateHeight = app.heightEditField.Value;
                        updateDenoise(app);
                    end
                end
            end
        end

        % Value changed function: widthEditField, widthEditField_2
        function widthEditFieldValueChanged(app, event)
            if event.Value > 0
                app.templateWidth = event.Value;
                updateDenoise(app);
            end
%             if app.widthEditField.Value > 0
%                 app.templateWidth = app.widthEditField.Value;
%                 updateDenoise(app);
%             end
        end

        % Value changed function: heightEditField, heightEditField_2
        function heightEditFieldValueChanged(app, event)
            if event.Value > 0
                app.templateHeight = event.Value;
                updateDenoise(app);
            end
%             if app.heightEditField.Value > 0
%                 app.templateHeight = app.heightEditField.Value;
%                 updateDenoise(app);
%             end
        end

        % Value changed function: SetSigmaSlider
        function SetSigmaSliderValueChanged(app, event)
%             value = app.SetSigmaSlider.Value;
%             disp('SetSigmaSliderValueChanged() is called');
            app.denoise = 'Gaussian';
            updateDenoise(app);
        end

        % Callback function: ConfirmButton, Prewitt1CheckBox, 
        % Prewitt2CheckBox, Roberts1CheckBox, Roberts2CheckBox, 
        % SetLaplaceoperatorButtonGroup, Sobel1CheckBox, Sobel2CheckBox
        function ConfirmButtonPushed(app, event)
            updateOperator(app);
        end

        % Value changed function: SharpeningApplyCheckBox
        function SharpeningApplyCheckBoxValueChanged(app, event)
            value = app.SharpeningApplyCheckBox.Value;
            updateDenoise(app);
        end

        % Value changed function: SharpnessSlider
        function SharpnessSliderValueChanged(app, event)
            value = app.SharpnessSlider.Value;
            updateDenoise(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageedgedetectionandenhancementtoolboxUIFigure = uifigure;
            app.ImageedgedetectionandenhancementtoolboxUIFigure.Name = 'Image edge detection and enhancement toolbox';
            app.FileMenu = uimenu(app.ImageedgedetectionandenhancementtoolboxUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.Panel = uipanel(app.ImageedgedetectionandenhancementtoolboxUIFigure);
            app.UIAxesOriginal = uiaxes(app.Panel);
            title(app.UIAxesOriginal, 'Original image')
            app.UIAxesEdge = uiaxes(app.Panel);
            title(app.UIAxesEdge, 'Edge detection')
            app.UIAxesSharpening = uiaxes(app.Panel);
            title(app.UIAxesSharpening, 'Image sharpening enhancement')
            app.controlTabGroup = uitabgroup(app.ImageedgedetectionandenhancementtoolboxUIFigure);
            app.SetthresholdTab = uitab(app.controlTabGroup);
            app.SetthresholdTab.Title = 'Set threshold';
            app.UIAxesHistogram = uiaxes(app.SetthresholdTab);
            title(app.UIAxesHistogram, 'Grayscale histogram')
            app.SetthresholdSlider = uislider(app.SetthresholdTab);
            app.SetthresholdSlider.Limits = [0 255];
            app.SetthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @SetthresholdSliderValueChanged, true);
            app.DenoiseTab = uitab(app.controlTabGroup);
            app.DenoiseTab.Title = 'Denoise';
            app.denoiseTabGroup = uitabgroup(app.DenoiseTab);
            app.denoiseTabGroup.SelectionChangedFcn = createCallbackFcn(app, @denoiseTabGroupSelectionChanged, true);
            app.MeanTab = uitab(app.denoiseTabGroup);
            app.MeanTab.Title = 'Mean';
            app.TemplatesizeButtonGroup = uibuttongroup(app.MeanTab);
            app.TemplatesizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TemplatesizeButtonGroupSelectionChanged, true);
            app.TemplatesizeButtonGroup.Title = 'Template size';
            app.customizeButton = uiradiobutton(app.TemplatesizeButtonGroup);
            app.customizeButton.Text = 'customize';
            app.widthEditFieldLabel = uilabel(app.TemplatesizeButtonGroup);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.TemplatesizeButtonGroup, 'numeric');
            app.widthEditField.ValueChangedFcn = createCallbackFcn(app, @widthEditFieldValueChanged, true);
            app.heightEditFieldLabel = uilabel(app.TemplatesizeButtonGroup);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.TemplatesizeButtonGroup, 'numeric');
            app.heightEditField.ValueChangedFcn = createCallbackFcn(app, @heightEditFieldValueChanged, true);
            app.x1Button = uiradiobutton(app.TemplatesizeButtonGroup);
            app.x1Button.Text = '1x1';
            app.x1Button.Value = true;
            app.x3Button_8 = uiradiobutton(app.TemplatesizeButtonGroup);
            app.x3Button_8.Text = '3x3';
            app.x5Button_8 = uiradiobutton(app.TemplatesizeButtonGroup);
            app.x5Button_8.Text = '5x5';
            app.x7Button_8 = uiradiobutton(app.TemplatesizeButtonGroup);
            app.x7Button_8.Text = '7x7';
            app.MedianTab = uitab(app.denoiseTabGroup);
            app.MedianTab.Title = 'Median';
            app.TemplatesizeButtonGroup_5 = uibuttongroup(app.MedianTab);
            app.TemplatesizeButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @TemplatesizeButtonGroupSelectionChanged, true);
            app.TemplatesizeButtonGroup_5.Title = 'Template size';
            app.x3Button_7 = uiradiobutton(app.TemplatesizeButtonGroup_5);
            app.x3Button_7.Text = '3x3';
            app.x5Button_7 = uiradiobutton(app.TemplatesizeButtonGroup_5);
            app.x5Button_7.Text = '5x5';
            app.x7Button_7 = uiradiobutton(app.TemplatesizeButtonGroup_5);
            app.x7Button_7.Text = '7x7';
            app.x1Button_2 = uiradiobutton(app.TemplatesizeButtonGroup_5);
            app.x1Button_2.Text = '1x1';
            app.x1Button_2.Value = true;
            app.customizeButton_2 = uiradiobutton(app.TemplatesizeButtonGroup_5);
            app.customizeButton_2.Text = 'customize';
            app.widthEditField_2Label = uilabel(app.TemplatesizeButtonGroup_5);
            app.widthEditField_2Label.Text = 'width';
            app.widthEditField_2 = uieditfield(app.TemplatesizeButtonGroup_5, 'numeric');
            app.widthEditField_2.ValueChangedFcn = createCallbackFcn(app, @widthEditFieldValueChanged, true);
            app.heightEditField_2Label = uilabel(app.TemplatesizeButtonGroup_5);
            app.heightEditField_2Label.Text = 'height';
            app.heightEditField_2 = uieditfield(app.TemplatesizeButtonGroup_5, 'numeric');
            app.heightEditField_2.ValueChangedFcn = createCallbackFcn(app, @heightEditFieldValueChanged, true);
            app.GaussianTab = uitab(app.denoiseTabGroup);
            app.GaussianTab.Title = 'Gaussian';
            app.SetsigmaButtonGroup = uibuttongroup(app.GaussianTab);
            app.SetsigmaButtonGroup.Title = 'Set sigma';
            app.SetSigmaSlider = uislider(app.SetsigmaButtonGroup);
            app.SetSigmaSlider.Limits = [0 20];
            app.SetSigmaSlider.ValueChangedFcn = createCallbackFcn(app, @SetSigmaSliderValueChanged, true);
            app.SharpeningTab = uitab(app.controlTabGroup);
            app.SharpeningTab.Title = 'Sharpening';
            app.SharpeningApplyCheckBox = uicheckbox(app.SharpeningTab);
            app.SharpeningApplyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SharpeningApplyCheckBoxValueChanged, true);
            app.SharpeningApplyCheckBox.Text = 'Apply denoising results to image sharpening results';
            app.SharpeningApplyCheckBox.Value = true;
            app.SharpnessSliderLabel = uilabel(app.SharpeningTab);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.SharpeningTab);
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @SharpnessSliderValueChanged, true);
            app.operatorTabGroup = uitabgroup(app.ImageedgedetectionandenhancementtoolboxUIFigure);
            app.operatorTabGroup.SelectionChangedFcn = createCallbackFcn(app, @operatorTabGroupSelectionChanged, true);
            app.SobelTab = uitab(app.operatorTabGroup);
            app.SobelTab.Title = 'Sobel';
            app.Sobel1CheckBox = uicheckbox(app.SobelTab);
            app.Sobel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Sobel1CheckBox.Text = 'Sobel operator 1: [-1,0,1;-2,0,2;-1,0,1]';
            app.Sobel1CheckBox.Value = true;
            app.Sobel2CheckBox = uicheckbox(app.SobelTab);
            app.Sobel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Sobel2CheckBox.Text = 'Sobel operator 2: [-1,-2,-1;0,0,0;1,2,1]';
            app.Sobel2CheckBox.Value = true;
            app.LaplaceTab = uitab(app.operatorTabGroup);
            app.LaplaceTab.Title = 'Laplace';
            app.SetLaplaceoperatorButtonGroup = uibuttongroup(app.LaplaceTab);
            app.SetLaplaceoperatorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.SetLaplaceoperatorButtonGroup.Title = 'Set Laplace operator';
            app.Laplace1Button = uiradiobutton(app.SetLaplaceoperatorButtonGroup);
            app.Laplace1Button.Text = 'Laplace operator 1: [0,1,0;1,-4,1;0,1,0]';
            app.Laplace1Button.Value = true;
            app.Laplace2Button = uiradiobutton(app.SetLaplaceoperatorButtonGroup);
            app.Laplace2Button.Text = 'Laplace operator 2: [1,1,1;1,-8,1;1,1,1]';
            app.Laplace3Button = uiradiobutton(app.SetLaplaceoperatorButtonGroup);
            app.Laplace3Button.Text = 'Laplace operator 3: [0,-1,0;-1,4,-1;0,-1,0]';
            app.Laplace4Button = uiradiobutton(app.SetLaplaceoperatorButtonGroup);
            app.Laplace4Button.Text = 'Laplace operator 4: [-1,-1,-1;-1,8,-1;-1,-1,-1]';
            app.PrewittTab = uitab(app.operatorTabGroup);
            app.PrewittTab.Title = 'Prewitt';
            app.Prewitt1CheckBox = uicheckbox(app.PrewittTab);
            app.Prewitt1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Prewitt1CheckBox.Text = 'Prewitt operator 1: [-1,-1,-1;0,0,0;1,1,1]';
            app.Prewitt1CheckBox.Value = true;
            app.Prewitt2CheckBox = uicheckbox(app.PrewittTab);
            app.Prewitt2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Prewitt2CheckBox.Text = 'Prewitt operator 2: [-1,0,1;-1,0,1;-1,0,1]';
            app.Prewitt2CheckBox.Value = true;
            app.RobertsTab = uitab(app.operatorTabGroup);
            app.RobertsTab.Title = 'Roberts';
            app.Roberts1CheckBox = uicheckbox(app.RobertsTab);
            app.Roberts1CheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Roberts1CheckBox.Text = 'Roberts operator 1: [0,0,0;0,-1,0;0,0,1]';
            app.Roberts1CheckBox.Value = true;
            app.Roberts2CheckBox = uicheckbox(app.RobertsTab);
            app.Roberts2CheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Roberts2CheckBox.Text = 'Roberts operator 2: [0,0,0;0,0,-1;0,1,0]';
            app.Roberts2CheckBox.Value = true;
            app.CannyTab = uitab(app.operatorTabGroup);
            app.CannyTab.Title = 'Canny';
            app.GradientlowthresholdEditFieldLabel = uilabel(app.CannyTab);
            app.GradientlowthresholdEditFieldLabel.Text = 'Gradient low threshold';
            app.CannyLowEditField = uieditfield(app.CannyTab, 'numeric');
            app.CannyLowEditField.Limits = [0 1];
            app.CannyLowEditField.Value = 0.1;
            app.GradienthighthresholdEditFieldLabel = uilabel(app.CannyTab);
            app.GradienthighthresholdEditFieldLabel.Text = 'Gradient high threshold';
            app.CannyHighEditField = uieditfield(app.CannyTab, 'numeric');
            app.CannyHighEditField.Limits = [0 1];
            app.CannyHighEditField.Value = 0.2;
            app.ConfirmButton = uibutton(app.CannyTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.TextArea = uitextarea(app.CannyTab);
            app.TextArea.Value = {'Note: This toolbox use sobel operator to calculate gradient. You can adjust the low and high threshold of gradient to see what happens. '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = myImageApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageedgedetectionandenhancementtoolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageedgedetectionandenhancementtoolboxUIFigure)
        end
    end
end